classdef finger_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        EnterRankNo     matlab.ui.control.EditField
        Label           matlab.ui.control.Label
        showtrue        matlab.ui.control.Button
        showmatch       matlab.ui.control.Button
        Rankofpts       matlab.ui.control.Table
        match           matlab.ui.control.Button
        showIcnImage    matlab.ui.control.Button
        quit            matlab.ui.control.Button
        GetIcn          matlab.ui.control.Button
        LoadFinger      matlab.ui.control.Button
        FingerImageAxe  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFinger
        function LoadFingerButtonPushed(app, event)
            global img;
            global filename;
            [filename, pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.gif'}, ...
                'Pick a Fingerprint Image File In Search');
            im = imread([pathname,filename]);
            if ndims(im) == 3
                im = rgb2gray(im);
            end
            img = im;
            imshow(im,'Parent',app.FingerImageAxe);
        end

        % Button pushed function: GetIcn
        function GetIcnButtonPushed(app, event)
            global img;
            global filename;
            mask = imread(['data1\search\mask\',filename]);
            getIcn_search(img, mask);
            msgbox(["已完成", newline, "特征点txt文件以保存至IcnSearch\Icn.txt"],'提示',"help");
        end

        % Button pushed function: quit
        function quitButtonPushed(app, event)
            choice = questdlg("确定关闭吗？","关闭","YES","NO","NO");
            switch choice
                case "YES"
                    delete(app.UIFigure); %关闭所有窗口
                    return;
                case "NO"
                    return;
            end 
        end

        % Button pushed function: showIcnImage
        function showIcnImageButtonPushed(app, event)
            global img;
            minu = LoadNeuFeature('IcnSearch\Icn.txt');
            minu(:,3) = -minu(:,3);
            fig = figure(1);
            imshow(img),title('含特征点指纹图片');
            DrawMinu(fig,minu);
        end

        % Button pushed function: match
        function matchButtonPushed(app, event)
            global img;
            global num;
            global match_pts1; %现场指纹的匹配结果
            global match_pts2; %库指纹的匹配结果
            global rank; %1 排名 2编号 3分数
            
            folder = 'IcnOut\';
            files = dir(fullfile(folder, '*.txt'));
            num = numel(files);
     
            match_pts1 = cell(1,num);
            match_pts2 = cell(1,num);
            rank = zeros(3,num);
            rank(2,:) = 1:num;
            app.Rankofpts.Data = rank';
            minu_new = LoadNeuFeature('IcnSearch\Icn.txt');
            h = waitbar(0,'wait...');
            for i = 1:num
                 %88号图出了点问题，之后修改
                waitbar(i/num,h);
                %app.EnterRankNo.Value = num2str(i);
                minuofset = LoadNeuFeature(strcat('IcnOut\Icn',num2str(i),'.txt'));
                [match_pts2{i}, match_pts1{i}, rank(3,i)] = match2(img,minuofset,img,minu_new);%输出与img无关
            end
            rank = sortrows(rank',3,"descend")';
            rank(1,:) = 1:num;
            app.Rankofpts.ColumnFormat{1,2} = 'numeric';
            app.Rankofpts.Data = rank';
            close(h);
        end

        % Button pushed function: showmatch
        function showmatchButtonPushed(app, event)
            global rank;
            global match_pts2;
            global match_pts1;
            global img;

            id = str2num(app.EnterRankNo.Value);
            pid = rank(2,id);
            pts = rank(3,id);
            mpts1 = match_pts1{pid}; %ofnew
            mpts2 = match_pts2{pid}; %ofset
            imgofset = imread(strcat('data1\file\img\',num2str(pid),'.png'));
            figure(2),showMatchedFeatures(img,imgofset,mpts1,mpts2,'montage'),title(strcat("Match Vision with ", ...
                num2str(pid), ".png  ( rank ",num2str(id)," )"));
            msgbox(strcat("匹配编号为",num2str(pid),"，匹配排行为",num2str(id),"，匹配分数为",num2str(pts)),'匹配结果','help');
        end

        % Button pushed function: showtrue
        function showtrueButtonPushed(app, event)
            global rank;
            global match_pts2;
            global match_pts1;
            global img;
            global filename;

            p = regexp(filename,'\d+','match');
            pid = p{1};
            pid = str2num(pid);

            ind = find(rank(2,:) == pid);
            prank = ind;
            pts = rank(3,prank);

            %app.EnterRankNo.Value = num2str(pts);

            id = prank;


            mpts1 = match_pts1{pid}; %ofnew
            mpts2 = match_pts2{pid}; %ofset
            imgofset = imread(strcat('data1\file\img\',num2str(pid),'.png'));
            figure(2),showMatchedFeatures(img,imgofset,mpts1,mpts2,'montage'),title(strcat("Match Vision with ", ...
                num2str(pid), ".png  ( rank ",num2str(id)," )"));

            msgbox(strcat("真匹配编号为",num2str(pid),"，匹配排行为",num2str(id),"，匹配分数为",num2str(pts)),'真匹配结果','help');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FingerImageAxe = uiaxes(app.GridLayout);
            zlabel(app.FingerImageAxe, 'Z')
            app.LoadFinger = uibutton(app.GridLayout, 'push');
            app.LoadFinger.ButtonPushedFcn = createCallbackFcn(app, @LoadFingerButtonPushed, true);
            app.LoadFinger.Text = '载入现场指纹';
            app.GetIcn = uibutton(app.GridLayout, 'push');
            app.GetIcn.ButtonPushedFcn = createCallbackFcn(app, @GetIcnButtonPushed, true);
            app.GetIcn.Text = '特征点提取';
            app.quit = uibutton(app.GridLayout, 'push');
            app.quit.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quit.Text = '退出';
            app.showIcnImage = uibutton(app.GridLayout, 'push');
            app.showIcnImage.ButtonPushedFcn = createCallbackFcn(app, @showIcnImageButtonPushed, true);
            app.showIcnImage.Text = '显示含特征点指纹图片';
            app.match = uibutton(app.GridLayout, 'push');
            app.match.ButtonPushedFcn = createCallbackFcn(app, @matchButtonPushed, true);
            app.match.Text = '匹配库指纹';
            app.Rankofpts = uitable(app.GridLayout);
            app.Rankofpts.ColumnName = {'排名'; '编号'; '匹配分数'};
            app.Rankofpts.RowName = {};
            app.showmatch = uibutton(app.GridLayout, 'push');
            app.showmatch.ButtonPushedFcn = createCallbackFcn(app, @showmatchButtonPushed, true);
            app.showmatch.Text = ' 查看配对图';
            app.showtrue = uibutton(app.GridLayout, 'push');
            app.showtrue.ButtonPushedFcn = createCallbackFcn(app, @showtrueButtonPushed, true);
            app.showtrue.Text = '查看真匹配情况';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '查看排名';
            app.EnterRankNo = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finger_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end